classdef MetodosNumericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuprincipalUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        MTODOSNUMERICOSLabel            matlab.ui.control.Label
        ByMiguelAcevedoLabel            matlab.ui.control.Label
        LuisPuelloAlfredoAlmaralesLabel  matlab.ui.control.Label
        CrditosaMathworksLabel          matlab.ui.control.Label
        QuesunmtodonumricoLabel         matlab.ui.control.Label
        BISECCIONLabel                  matlab.ui.control.Label
        PUNTOFIJOLabel                  matlab.ui.control.Label
        NEWTONRAPHSONLabel              matlab.ui.control.Label
        REGLAFALSALabel                 matlab.ui.control.Label
        SECANTELabel                    matlab.ui.control.Label
        BiseccinTab                     matlab.ui.container.Tab
        IngreselafuncionfxEditFieldLabel  matlab.ui.control.Label
        IngreselafuncionbiseccionfxEditField  matlab.ui.control.EditField
        IngreseelvaloraLabel            matlab.ui.control.Label
        IngreseelvaloraEditField        matlab.ui.control.NumericEditField
        IngreselatoleranciaEditFieldLabel  matlab.ui.control.Label
        IngreselatoleranciaEditField    matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.StateButton
        IngreseelvalorbEditFieldLabel   matlab.ui.control.Label
        IngreseelvalorbEditField        matlab.ui.control.NumericEditField
        GraficarButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NewtonRaphsonTab                matlab.ui.container.Tab
        IngreselafuncionfxEditField_2Label  matlab.ui.control.Label
        IngreselafuncionNewtonRaphsonfxEditField_2  matlab.ui.control.EditField
        Ingreseelvalorx0EditFieldLabel  matlab.ui.control.Label
        Ingreseelvalorx0EditField       matlab.ui.control.NumericEditField
        IngreselatoleranciaEditField_2Label  matlab.ui.control.Label
        IngreselatoleranciaEditField_2  matlab.ui.control.NumericEditField
        CalcularButton_2                matlab.ui.control.StateButton
        GraficarButton_2                matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        IngreseelvalorabEditFieldLabel  matlab.ui.control.Label
        IngreseelvalorabEditField       matlab.ui.control.NumericEditField
        IngreseelvalorbEditField_3      matlab.ui.control.NumericEditField
        PuntofijoTab                    matlab.ui.container.Tab
        IngreselafuncionfxEditField_2Label_2  matlab.ui.control.Label
        IngreselafuncionNewtonRaphsonfxEditField_3  matlab.ui.control.EditField
        Ingreseelvalorx0EditField_2Label  matlab.ui.control.Label
        Ingreseelvalorx0EditField_2     matlab.ui.control.NumericEditField
        IngreselatoleranciaEditField_3Label  matlab.ui.control.Label
        IngreselatoleranciaEditField_3  matlab.ui.control.NumericEditField
        CalcularButton_3                matlab.ui.control.StateButton
        GraficarButton_3                matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        ReglafalsaTab                   matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        IngreselafuncionfxEditFieldLabel_2  matlab.ui.control.Label
        IngreselafuncionbiseccionfxEditField_2  matlab.ui.control.EditField
        IngreseelvaloraEditField_2Label  matlab.ui.control.Label
        IngreseelvaloraEditField_2      matlab.ui.control.NumericEditField
        IngreseelvalorbEditField_2Label  matlab.ui.control.Label
        IngreseelvalorbEditField_2      matlab.ui.control.NumericEditField
        IngreselatoleranciaEditField_4Label  matlab.ui.control.Label
        IngreselatoleranciaEditField_4  matlab.ui.control.NumericEditField
        CalcularButton_4                matlab.ui.control.StateButton
        GraficarButton_4                matlab.ui.control.Button
        SecanteTab                      matlab.ui.container.Tab
        IngreselafuncionfxEditFieldLabel_3  matlab.ui.control.Label
        IngreselafuncionbiseccionfxEditField_3  matlab.ui.control.EditField
        IngreseelvaloraEditField_3Label  matlab.ui.control.Label
        IngreseelvaloraEditField_3      matlab.ui.control.NumericEditField
        IngreselatoleranciaEditField_5Label  matlab.ui.control.Label
        IngreselatoleranciaEditField_5  matlab.ui.control.NumericEditField
        CalcularButton_5                matlab.ui.control.StateButton
        GraficarButton_5                matlab.ui.control.Button
        UITable_3                       matlab.ui.control.Table
        IngreseelvalorbEditField_4Label  matlab.ui.control.Label
        IngreseelvalorbEditField_4      matlab.ui.control.NumericEditField
        AcercadeTab                     matlab.ui.container.Tab
    end


    methods (Access = public)
    
        function results = funcionBiseccion(app)
            syms funcion;
            funcion = app.IngreselafuncionbiseccionfxEditField.Value;
            results = funcion;
        end
        
    end

    methods (Access = public)
    
        function results = valorAbiseccion(app)
            results = app.IngreseelvaloraEditField.Value;
        end
        
    end
    
    methods (Access = public)
    
        function results = valorBbiseccion(app)
            results = app.IngreseelvalorbEditField.Value;
        end
        
    end

    methods (Access = public)
    
        function results = toleranciBiseccion(app)
             results = app.IngreselatoleranciaEditField.Value;
            
        end
        
    end

    methods (Access = public)
    
        function results = llenarTablaBiseccion(app)
        syms funcion; syms fun;
       %Funcion principal
            funcion = funcionBiseccion(app);
       %Valores iniciales 
            a = double(valorAbiseccion(app));
            b = double(valorBbiseccion(app));
            tol = double(toleranciBiseccion(app));
           
            fa = double(subs(funcion,'x',a));
            fb = double(subs(funcion,'x',b));
            fp = 0;            
            ea = double(100);
         
            valores = [];
            
            i = 1;
            
            if fa*fb < 0
                while ea > tol && tol ~= 0
                
                    fun = funcion;
                        if i-1 == 0
                           p = double(((a+b)/2));
                           fa = double(subs(fun,'x',a));
                           fb = double(subs(fun,'x',b));
                           fp = double(subs(fun,'x',p));
                           ea = 100;                                   
                           
                        else
                            if fa*fp < 0
                                anterior = double(p);
                                a = double(a);
                                b = double(p);
                                p = double(((a+b)/2));
                                fa = double(subs(fun,a));
                                fb = double(subs(fun,b));
                                fp = double(subs(fun,p));
                                ea = double(abs((p-anterior)));
                            else
                                if fp*fb < 0
                                    anterior = double(p);
                                    a = double(p);
                                    b = double(b);
                                    p = double(((a+b)/2));
                                    fa = double(subs(fun,a));
                                    fb = double(subs(fun,b));
                                    fp = double(subs(fun,p));
                                    ea = double(abs((p-anterior)));
                                    
                                end
                            end
                        end
                   
                    valores(i,1) = i-1;
                    valores(i,2) = a;
                    valores(i,3) = b;
                    valores(i,4) = p;
                    valores(i,5) = fa;
                    valores(i,6) = fb;
                    valores(i,7) = fp;
                    valores(i,8) = ea;
                    i=i+1;                                
                end
                   
            else
                    uialert(app.MenuprincipalUIFigure,'La funcion no es lineal en el intervalo', 'Invalid File');
                    return;
            end
            set(app.UITable,'data',valores);
            results = app.UITable;
        end

    end
     methods (Access = public)
     
         function results = funcionNewtonRaphson(app)
             syms funcion;
              funcion = app.IngreselafuncionNewtonRaphsonfxEditField_2.Value;
              results = funcion;
             
         end
         
     end
     methods (Access = public)
     
         function results = valorA_NewtonRaphson(app)
              results = app.IngreseelvalorabEditField.Value;
             
         end
         
     end
     
     methods (Access = public)
     
         function results = valorB_NewtonRaphson(app)
              results = app.IngreseelvalorbEditField_3.Value;
             
         end
         
     end
    
    methods (Access = public)
    
        function results = valorXNewtonRaphson(app)
             results = app.Ingreseelvalorx0EditField.Value;
            
        end
        
    end
    
    methods (Access = public)
    
        function results = toleranciaNewtonRaphson(app)
             results = app.IngreselatoleranciaEditField_2.Value;
            
        end        
    end

    methods (Access = public)
    
        function results = llenarTablaNewtonRaphson(app)
            syms funcion; syms x; syms primeraDerivada;
            syms segundaDerivada;
            
            % variables simbolicas
            funcion = funcionNewtonRaphson(app);
            primeraDerivada = diff(funcion,x);
            segundaDerivada = diff(primeraDerivada,x);
            
            % datos ingresados por el usuario
            xi = double(valorXNewtonRaphson(app));            
            tol = double(toleranciaNewtonRaphson(app));
            
            % error absoluto y contador            
            ea = 100;
            i = 1;

            % Matriz de valores
            valores = [];
            
            while ea > tol && tol ~= 0
                fun = funcion;
                dx = primeraDerivada;
                dx2 = segundaDerivada;
                x0 = xi;
                if i == 1
                    x0 = xi;                    
                    fxi = double(subs(fun,x0));
                    dxi = double(subs(dx,x0));
                    ea = 100;
                else
                    xi = double(x0-((fxi)/(dxi)));
                    fxi = double(subs(fun,xi));
                    dxi = double(subs(dx,xi));
                    ea = double(abs(xi - x0));
                end
                valores(i,1) = i-1;
                valores(i,2) = xi;
                valores(i,3) = fxi;
                valores(i,4) = dxi;
                valores(i,5) = ea;
                i = i+1;
            end
            set(app.UITable2,'data',valores);
            results = app.UITable2;                 
        end
        
    end
    
    methods (Access = public)
    
        function results = funcionReglaFalsa(app)
            syms funcion;
            funcion = app.IngreselafuncionbiseccionfxEditField_2.Value;
            results = funcion;
        end
        
    end

    methods (Access = public)
    
        function results = valorAReglaFalsa(app)
            results = app.IngreseelvaloraEditField_2.Value;
        end
        
    end
    
    methods (Access = public)
    
        function results = valorBReglaFalsa(app)
            results = app.IngreseelvalorbEditField_2.Value;
        end
        
    end

    methods (Access = public)
    
        function results = toleranciReglaFalsa(app)
             results = app.IngreselatoleranciaEditField_4.Value;
            
        end
        
    end
    
     methods (Access = public)
    
         function results = llenarTablaReglaFalsa(app)
             syms funcion; syms fun;
             
             %variables simbolicas 
             funcion = funcionReglaFalsa(app);
             
             %valores iniciales dados por el usuario
             x0 = valorAReglaFalsa(app);
             x1 = valorBReglaFalsa(app);
             tol = toleranciReglaFalsa(app);
             
             %evaluando la funcion en los limites
             fx0 = double(subs(funcion,x0));
             fx1 = double(subs(funcion,x1));
             
             %valores propios
             ea = 100;
             i = 1;
             valores = [];
             
             if fx0 * fx1 < 0
                 while ea > tol && tol ~= 0
                     fun = funcion;
                     x0 = x0;
                     x1 = x1;
                     if i == 1
                         x0 = x0;
                         fxi = double(subs(fun,x0));
                         x1 = x1;
                         fxi1 = double(subs(fun,x1));
                         xp = double(x0-(fx0*(x1-x0)/(fx1-fx0)));
                         fxp = double(subs(fun,xp));
                         ea = 100;
                     else 
                         if fx0 * fxp < 0
                             xpAux = xp;
                             x0 = x0;
                             fxi = double(subs(fun,x0));
                             x1 = xp;
                             fxi1 = double(subs(fun,x1));
                             xp = double(x0-(fx0*(x1-x0)/(fxi1-fxi)));
                             fxp = double(subs(fun,xp));
                             ea = double(abs(xp - xpAux));
                             
                         else
                             if fx0 * fxp > 0
                                 xpAux = xp;
                             x0 = x0;
                             fxi = double(subs(fun,x0));
                             x1 = xp;
                             fxi1 = double(subs(fun,x1));
                             xp = double(x0-(fx0*(x1-x0)/(fxi1-fxi)));
                             fxp = double(subs(fun,xp));
                             ea = double(abs(xp - xpAux));
                             else 
                                 if fx0 * fxp == 0
                                     xpAux = xp;
                                     x0 = x0;
                                     fxi = double(subs(fun,x0));
                                     x1 = x1;
                                     fxi1 = double(subs(fun,x1));
                                     xp = double(x0-(fx0*(x1-x0)/(fxi1-fxi)));
                                     fxp = double(subs(fun,xp));
                                     ea = double(abs(xp - xpAux));
                                     
                                 end 
                             end
                         end
                     end
                    valores(i,1) = i-1;
                    valores(i,2) = x0;
                    valores(i,3) = fxi;
                    valores(i,4) = x1;
                    valores(i,5) = fxi1;
                    valores(i,6) = xp;
                    valores(i,7) = fxp;
                    valores(i,8) = ea;
                    i = i+1;
                 end
             else
                  uialert(app.MenuprincipalUIFigure,'La funcion no es lineal en el intervalo', 'Invalid File');
                    return;
             end
             set(app.UITable_2,'data',valores);
             results = app.UITable_2;
            
        end
        
     end
     
     
    methods (Access = public)
    
          function results = funcionSecante(app)
            syms funcion;
            funcion = app.IngreselafuncionbiseccionfxEditField_3.Value;
            results = funcion;
        end
        
    end

    methods (Access = public)
    
        function results = valorASecante(app)
            results = app.IngreseelvaloraEditField_3.Value;
        end
        
    end
    
    methods (Access = public)
    
        function results = valorBSecante(app)
            results = app.IngreseelvalorbEditField_4.Value;
        end
        
    end

    methods (Access = public)
    
        function results = toleranciSecante(app)
             results = app.IngreselatoleranciaEditField_5.Value;
            
        end
        
    end
    
    methods (Access = public)
        function results = llenarTablaSecante(app)
            syms funcion; syms fun;
            syms x; syms primeraDerivada;
            
            %Variables simbolicas 
            funcion = funcionSecante(app);
            primeraDerivada = diff(funcion,x);
            
            %valores suministrados por el usuario
            x0 = valorASecante(app);
            x1 = valorBSecante(app);
            tol = toleranciSecante(app);
            
            %evaluando la funcion
            fx0 = double(subs(funcion,x0));
            fx1 = double(subs(funcion,x1));                       
            
            %valores propios
            valores= [];
            i = 1;
            ea = 100;
            
            if fx0 * fx1 < 0
                while ea > tol && tol ~= 0                
                    fun = funcion;
                    primeraDerivada = primeraDerivada;
                    xi_1 = x0;
                    xi = x1
                    if i == 1
                        xi_1 = x0;
                        fxi_1 = double(subs(fun,xi_1));
                        xi = x1;
                        fxi = double(subs(fun,xi));
                        xi1 = double(xi-(fxi*(xi-xi_1)/(fxi-fxi_1)));  
                        fxi1 = double(subs(fun,xi1));
                        ea = 100;
                    else
                        if i == 2
                            xi_1 = xi;
                            fxi_1 = double(subs(fun,xi_1));
                            xi = xi1;
                            fxi = double(subs(fun,xi));
                            xi1 = double(xi-(fxi*(xi-xi_1)/(fxi-fxi_1)));
                            fxi1 = double(subs(fun,xi1));
                            ea = double(abs(xi1 - xi));                            
                        else                            
                            if i > 2
                                xi_1 = xi;
                                fxi_1 = double(subs(fun,xi_1));
                                xi = xi1;
                                fxi = double(subs(fun,xi));
                                xi1 = double(xi-(fxi*(xi-xi_1)/(fxi-fxi_1)));
                                fxi1 = double(subs(fun,xi1));
                                ea = double(abs(xi1 - xi));                                
                            end                            
                        end
                    end
                    valores(i,1) = i-1;
                    valores(i,2) = xi_1;
                    valores(i,3) = fxi_1;
                    valores(i,4) = xi;
                    valores(i,5) = fxi;
                    valores(i,6) = xi1;
                    valores(i,7) = fxi1;
                    valores(i,8) = ea;
                    i = i+1;
                end
            else
                uialert(app.MenuprincipalUIFigure,'La funcion no es lineal en el intervalo', 'Invalid File');
                    return;
            end
            set(app.UITable_3,'Data',valores);
            results = app.UITable_3;
        end
    end


    methods (Access = private)

        % Callback function
        function MenuprincipalUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
            value = app.CalcularButton.Value;      
            llenarTablaBiseccion(app);               
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            syms fun;
            fun = funcionBiseccion(app);
            a = valorAbiseccion(app);
            b = valorBbiseccion(app);
            intervalo = [a b];
            intervalo1 = [-10 10];
            fplot(fun,intervalo);
            if a ~= 0 || b ~= 0
                fplot(fun,intervalo);
                grid on;
            else
                fplot(fun,intervalo1);
                grid on;
            end
        end

        % Value changed function: CalcularButton_2
        function CalcularButton_2ValueChanged(app, event)
            value = app.CalcularButton_2.Value;
            llenarTablaNewtonRaphson(app);
           % llenarTablaNewtonRaphson(app);
        end

        % Button pushed function: GraficarButton_2
        function GraficarButton_2Pushed(app, event)
            syms fun;
            fun = funcionNewtonRaphson(app);
            a = double(valorA_NewtonRaphson(app));
            b = double(valorB_NewtonRaphson(app));
            intervalo = [a b];
            intervalo1 = [-10 10];
            if a ~= 0 || b ~= 0
                fplot(fun,intervalo);
                grid on;
            else
                fplot(fun,intervalo1);
                grid on;
            end
            
        end

        % Value changed function: CalcularButton_4
        function CalcularButton_4ValueChanged(app, event)
            value = app.CalcularButton_4.Value;
            llenarTablaReglaFalsa(app);
        end

        % Button pushed function: GraficarButton_4
        function GraficarButton_4Pushed(app, event)
            syms fun;
            fun = funcionReglaFalsa(app);
            a = double(valorAReglaFalsa(app));
            b = double(valorBReglaFalsa(app));
            intervalo = [a b];
            intervalo1 = [-10 10];
            if a ~= 0 ||b ~= 0
                fplot(fun,intervalo);
                grid on;
            else
                fplot(fun,intervalo1);
                grid on;
            end
        end

        % Value changed function: CalcularButton_5
        function CalcularButton_5ValueChanged(app, event)
            value = app.CalcularButton_5.Value;
            llenarTablaSecante(app);
        end

        % Button pushed function: GraficarButton_5
        function GraficarButton_5Pushed(app, event)
            syms fun;
            fun = funcionSecante(app);
            a = double(valorASecante(app));
            b = double(valorBSecante(app));
            intervalo = [a b];
            intervalo1 = [-10 10];
            if a ~= 0 || b ~= 0
                fplot(fun,intervalo);
                grid on;
            else
                fplot(fun,intervalo1);
                grid on;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuprincipalUIFigure = uifigure;
            app.MenuprincipalUIFigure.Name = 'Menu principal';
            app.TabGroup = uitabgroup(app.MenuprincipalUIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.TextArea = uitextarea(app.InicioTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; ''; ''; ''; 'Un método numérico es un procedimiento mediante el cual se obtiene, casi siempre de manera aproximada, la solución de ciertos problemas realizando cálculos puramente aritméticos y lógicos (operaciones aritméticas elementales, cálculo de funciones, consulta de una tabla de valores, cálculo preposicional, etc.). Un tal procedimiento consiste de una lista finita de instrucciones precisas que especifican una secuencia de operaciones algebraicas y lógicas (algoritmo), que producen o bien una aproximación de la solución del problema (solución numérica) o bien un mensaje. La eficiencia en el cálculo de dicha aproximación depende, en parte, de la facilidad de implementación del algoritmo y de las características especiales y limitaciones de los instrumentos de cálculo (los computadores). En general, al emplear estos instrumentos de cálculo se introducen errores llamados de redondeo.'};
            app.TextArea_2 = uitextarea(app.InicioTab);
            app.TextArea_2.Editable = 'off';
            app.MTODOSNUMERICOSLabel = uilabel(app.InicioTab);
            app.MTODOSNUMERICOSLabel.Text = 'MÉTODOS  NUMERICOS';
            app.ByMiguelAcevedoLabel = uilabel(app.InicioTab);
            app.ByMiguelAcevedoLabel.Text = 'By:     Miguel Acevedo';
            app.LuisPuelloAlfredoAlmaralesLabel = uilabel(app.InicioTab);
            app.LuisPuelloAlfredoAlmaralesLabel.Text = '     Luis Puello       Alfredo Almarales';
            app.CrditosaMathworksLabel = uilabel(app.InicioTab);
            app.CrditosaMathworksLabel.Text = 'Créditos a: Mathworks';
            app.QuesunmtodonumricoLabel = uilabel(app.InicioTab);
            app.QuesunmtodonumricoLabel.Text = {'¿Qué es un método numérico?'; ''};
            app.BISECCIONLabel = uilabel(app.InicioTab);
            app.BISECCIONLabel.Text = 'BISECCION';
            app.PUNTOFIJOLabel = uilabel(app.InicioTab);
            app.PUNTOFIJOLabel.Text = 'PUNTO FIJO';
            app.NEWTONRAPHSONLabel = uilabel(app.InicioTab);
            app.NEWTONRAPHSONLabel.Text = 'NEWTON-RAPHSON';
            app.REGLAFALSALabel = uilabel(app.InicioTab);
            app.REGLAFALSALabel.Text = 'REGLA FALSA';
            app.SECANTELabel = uilabel(app.InicioTab);
            app.SECANTELabel.Text = 'SECANTE';
            app.BiseccinTab = uitab(app.TabGroup);
            app.BiseccinTab.Title = 'Bisección';
            app.IngreselafuncionfxEditFieldLabel = uilabel(app.BiseccinTab);
            app.IngreselafuncionfxEditFieldLabel.Text = 'Ingrese la funcion f(x): ';
            app.IngreselafuncionbiseccionfxEditField = uieditfield(app.BiseccinTab, 'text');
            app.IngreseelvaloraLabel = uilabel(app.BiseccinTab);
            app.IngreseelvaloraLabel.Text = 'Ingrese el valor [a, ]:  ';
            app.IngreseelvaloraEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.IngreselatoleranciaEditFieldLabel = uilabel(app.BiseccinTab);
            app.IngreselatoleranciaEditFieldLabel.Text = 'Ingrese la tolerancia: ';
            app.IngreselatoleranciaEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.CalcularButton = uibutton(app.BiseccinTab, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Text = 'Calcular';
            app.IngreseelvalorbEditFieldLabel = uilabel(app.BiseccinTab);
            app.IngreseelvalorbEditFieldLabel.Text = 'Ingrese el valor [ ,b]: ';
            app.IngreseelvalorbEditField = uieditfield(app.BiseccinTab, 'numeric');
            app.GraficarButton = uibutton(app.BiseccinTab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UITable = uitable(app.BiseccinTab);
            app.UITable.ColumnName = {'i'; 'ai'; 'bi'; 'pi'; 'f(ai)'; 'f(bi)'; 'f(pi)'; '|pi-pi-1|'};
            app.UITable.RowName = {};
            app.NewtonRaphsonTab = uitab(app.TabGroup);
            app.NewtonRaphsonTab.Title = 'Newton-Raphson';
            app.IngreselafuncionfxEditField_2Label = uilabel(app.NewtonRaphsonTab);
            app.IngreselafuncionfxEditField_2Label.Text = 'Ingrese la funcion f(x): ';
            app.IngreselafuncionNewtonRaphsonfxEditField_2 = uieditfield(app.NewtonRaphsonTab, 'text');
            app.Ingreseelvalorx0EditFieldLabel = uilabel(app.NewtonRaphsonTab);
            app.Ingreseelvalorx0EditFieldLabel.Text = 'Ingrese el valor x0:  ';
            app.Ingreseelvalorx0EditField = uieditfield(app.NewtonRaphsonTab, 'numeric');
            app.IngreselatoleranciaEditField_2Label = uilabel(app.NewtonRaphsonTab);
            app.IngreselatoleranciaEditField_2Label.Text = 'Ingrese la tolerancia: ';
            app.IngreselatoleranciaEditField_2 = uieditfield(app.NewtonRaphsonTab, 'numeric');
            app.CalcularButton_2 = uibutton(app.NewtonRaphsonTab, 'state');
            app.CalcularButton_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2ValueChanged, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.GraficarButton_2 = uibutton(app.NewtonRaphsonTab, 'push');
            app.GraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_2Pushed, true);
            app.GraficarButton_2.Text = 'Graficar';
            app.UITable2 = uitable(app.NewtonRaphsonTab);
            app.UITable2.ColumnName = {'i'; 'xi = g(x(i-1))'; 'f(xi)'; 'f ''(xi)'; '|xi - x(i-1)|'};
            app.UITable2.RowName = {};
            app.IngreseelvalorabEditFieldLabel = uilabel(app.NewtonRaphsonTab);
            app.IngreseelvalorabEditFieldLabel.Text = 'Ingrese el valor [a,b]:  ';
            app.IngreseelvalorabEditField = uieditfield(app.NewtonRaphsonTab, 'numeric');
            app.IngreseelvalorbEditField_3 = uieditfield(app.NewtonRaphsonTab, 'numeric');
            app.PuntofijoTab = uitab(app.TabGroup);
            app.PuntofijoTab.Title = 'Punto fijo';
            app.IngreselafuncionfxEditField_2Label_2 = uilabel(app.PuntofijoTab);
            app.IngreselafuncionfxEditField_2Label_2.Text = 'Ingrese la funcion f(x): ';
            app.IngreselafuncionNewtonRaphsonfxEditField_3 = uieditfield(app.PuntofijoTab, 'text');
            app.Ingreseelvalorx0EditField_2Label = uilabel(app.PuntofijoTab);
            app.Ingreseelvalorx0EditField_2Label.Text = 'Ingrese el valor x0:  ';
            app.Ingreseelvalorx0EditField_2 = uieditfield(app.PuntofijoTab, 'numeric');
            app.IngreselatoleranciaEditField_3Label = uilabel(app.PuntofijoTab);
            app.IngreselatoleranciaEditField_3Label.Text = 'Ingrese la tolerancia: ';
            app.IngreselatoleranciaEditField_3 = uieditfield(app.PuntofijoTab, 'numeric');
            app.CalcularButton_3 = uibutton(app.PuntofijoTab, 'state');
            app.CalcularButton_3.Text = 'Calcular';
            app.GraficarButton_3 = uibutton(app.PuntofijoTab, 'push');
            app.GraficarButton_3.Text = 'Graficar';
            app.UITable2_2 = uitable(app.PuntofijoTab);
            app.UITable2_2.ColumnName = {'i'; 'xi = g(x(i-1))'; 'f(xi)'; '|xi - x(i-1)|'};
            app.UITable2_2.RowName = {};
            app.ReglafalsaTab = uitab(app.TabGroup);
            app.ReglafalsaTab.Title = 'Regla falsa';
            app.UITable_2 = uitable(app.ReglafalsaTab);
            app.UITable_2.ColumnName = {'i'; 'xi'; 'f(xi)'; 'x(i+1)'; 'f(x(i+1))'; 'xr'; 'f(xr)'; '|pi-pi-1|'};
            app.UITable_2.RowName = {};
            app.IngreselafuncionfxEditFieldLabel_2 = uilabel(app.ReglafalsaTab);
            app.IngreselafuncionfxEditFieldLabel_2.Text = 'Ingrese la funcion f(x): ';
            app.IngreselafuncionbiseccionfxEditField_2 = uieditfield(app.ReglafalsaTab, 'text');
            app.IngreseelvaloraEditField_2Label = uilabel(app.ReglafalsaTab);
            app.IngreseelvaloraEditField_2Label.Text = 'Ingrese el valor [a, ]:  ';
            app.IngreseelvaloraEditField_2 = uieditfield(app.ReglafalsaTab, 'numeric');
            app.IngreseelvalorbEditField_2Label = uilabel(app.ReglafalsaTab);
            app.IngreseelvalorbEditField_2Label.Text = 'Ingrese el valor [ ,b]: ';
            app.IngreseelvalorbEditField_2 = uieditfield(app.ReglafalsaTab, 'numeric');
            app.IngreselatoleranciaEditField_4Label = uilabel(app.ReglafalsaTab);
            app.IngreselatoleranciaEditField_4Label.Text = 'Ingrese la tolerancia: ';
            app.IngreselatoleranciaEditField_4 = uieditfield(app.ReglafalsaTab, 'numeric');
            app.CalcularButton_4 = uibutton(app.ReglafalsaTab, 'state');
            app.CalcularButton_4.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_4ValueChanged, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.GraficarButton_4 = uibutton(app.ReglafalsaTab, 'push');
            app.GraficarButton_4.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_4Pushed, true);
            app.GraficarButton_4.Text = 'Graficar';
            app.SecanteTab = uitab(app.TabGroup);
            app.SecanteTab.Title = 'Secante';
            app.IngreselafuncionfxEditFieldLabel_3 = uilabel(app.SecanteTab);
            app.IngreselafuncionfxEditFieldLabel_3.Text = 'Ingrese la funcion f(x): ';
            app.IngreselafuncionbiseccionfxEditField_3 = uieditfield(app.SecanteTab, 'text');
            app.IngreseelvaloraEditField_3Label = uilabel(app.SecanteTab);
            app.IngreseelvaloraEditField_3Label.Text = 'Ingrese el valor  [a, ]:  ';
            app.IngreseelvaloraEditField_3 = uieditfield(app.SecanteTab, 'numeric');
            app.IngreselatoleranciaEditField_5Label = uilabel(app.SecanteTab);
            app.IngreselatoleranciaEditField_5Label.Text = 'Ingrese la tolerancia: ';
            app.IngreselatoleranciaEditField_5 = uieditfield(app.SecanteTab, 'numeric');
            app.CalcularButton_5 = uibutton(app.SecanteTab, 'state');
            app.CalcularButton_5.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_5ValueChanged, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.GraficarButton_5 = uibutton(app.SecanteTab, 'push');
            app.GraficarButton_5.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_5Pushed, true);
            app.GraficarButton_5.Text = 'Graficar';
            app.UITable_3 = uitable(app.SecanteTab);
            app.UITable_3.ColumnName = {'i'; 'xi-1'; 'f(xi-1)'; 'xi'; 'f(xi)'; 'xi+1'; 'f(xi+1)'; '|xi+1 - xi|'};
            app.UITable_3.RowName = {};
            app.IngreseelvalorbEditField_4Label = uilabel(app.SecanteTab);
            app.IngreseelvalorbEditField_4Label.Text = 'Ingrese el valor [ ,b]: ';
            app.IngreseelvalorbEditField_4 = uieditfield(app.SecanteTab, 'numeric');
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MetodosNumericos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuprincipalUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuprincipalUIFigure)
        end
    end
end